classdef VoiceChanger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ProcessedSoundLabel  matlab.ui.control.Label
        OrigionalSoundLabel  matlab.ui.control.Label
        PlayProcessedButton  matlab.ui.control.Button
        EffectsLabel         matlab.ui.control.Label
        ProcessedVoiceLabel  matlab.ui.control.Label
        OriginalVoiceLabel   matlab.ui.control.Label
        PitchDownButton      matlab.ui.control.Button
        LoadButton           matlab.ui.control.Button
        EchoButton           matlab.ui.control.Button
        RobotButton          matlab.ui.control.Button
        AlienButton          matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        PlayOriginalButton   matlab.ui.control.Button
        PitchUpButton        matlab.ui.control.Button
        MuffledButton        matlab.ui.control.Button
        MaleFemaleButton     matlab.ui.control.Button
        ProcessedAxes        matlab.ui.control.UIAxes
        OriginalAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    x       % Original audio
    y       % Processed audio
    fs      % Sampling frequency
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, path] = uigetfile('*.wav');
    if isequal(file,0)
        return;
    end

    [app.x, app.fs] = audioread(fullfile(path,file));
    app.x = app.x(:,1);
    app.x = app.x / max(abs(app.x));

    plot(app.OriginalAxes, app.x);
    title(app.OriginalAxes, 'Original Voice');
    xlabel(app.OriginalAxes,'Samples');
    ylabel(app.OriginalAxes,'Amplitude');
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            sound(app.x, app.fs);
        end

        % Button pushed function: EchoButton
        function EchoButtonPushed(app, event)
            app.y = echo_effect(app.x, app.fs, 0.3, 0.6);
    app.y = app.y / max(abs(app.y));
    sound(app.y, app.fs);

    plot(app.ProcessedAxes, app.y);
    title(app.ProcessedAxes,'Echo Effect');
        end

        % Button pushed function: PitchUpButton
        function PitchUpButtonPushed(app, event)
            app.y = pitch_shift(app.x, app.fs, 1/1.2);
    sound(app.y, app.fs);
    plot(app.ProcessedAxes, app.y);
    title(app.ProcessedAxes,'Pitch Up');
        end

        % Button pushed function: RobotButton
        function RobotButtonPushed(app, event)
             app.y = robot_voice(app.x, app.fs, 256, 0.5);
    sound(app.y, app.fs);
    plot(app.ProcessedAxes, app.y);
    title(app.ProcessedAxes,'Robot Voice');
        end

        % Button pushed function: MuffledButton
        function MuffledButtonPushed(app, event)
            app.y = muffled_voice(app.x, app.fs, 0.3, 50);
    sound(app.y, app.fs);
    plot(app.ProcessedAxes, app.y);
    title(app.ProcessedAxes,'Muffled Voice');
        end

        % Button pushed function: AlienButton
        function AlienButtonPushed(app, event)
            app.y = alien_voice(app.x, app.fs, 0.3, 50);
    sound(app.y, app.fs);
    plot(app.ProcessedAxes, app.y);
    title(app.ProcessedAxes,'Alien Voice');
        end

        % Button pushed function: MaleFemaleButton
        function MaleFemaleButtonPushed(app, event)
            app.y = male_to_female(app.x, app.fs);
    sound(app.y, app.fs);
    plot(app.ProcessedAxes, app.y);
    title(app.ProcessedAxes,'Male to Female');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite('gui_output.wav', app.y, app.fs);
        end

        % Button pushed function: PlayProcessedButton
        function PlayProcessedButtonPushed(app, event)
             if isempty(app.y)
        uialert(app.UIFigure, 'No processed audio available.', 'Error');
        return;
    end
    sound(app.y, app.fs);
        end

        % Button pushed function: PitchDownButton
        function PitchDownButtonPushed(app, event)
            if isempty(app.x)
        uialert(app.UIFigure, 'Load audio first.', 'Error');
        return;
    end

    % Pitch down (lower voice)
    app.y = pitch_shift(app.x, app.fs, 1.2);

    % Normalize
    app.y = app.y / max(abs(app.y));

    % Plot processed voice
    plot(app.ProcessedAxes, app.y);
    title(app.ProcessedAxes, 'Pitch Down Voice');
    xlabel(app.ProcessedAxes, 'Samples');
    ylabel(app.ProcessedAxes, 'Amplitude');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalAxes = uiaxes(app.UIFigure);
            title(app.OriginalAxes, 'OriginalAxes')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.ProcessedAxes = uiaxes(app.UIFigure);
            title(app.ProcessedAxes, 'ProcessedAxes')
            xlabel(app.ProcessedAxes, 'X')
            ylabel(app.ProcessedAxes, 'Y')
            zlabel(app.ProcessedAxes, 'Z')
            app.MaleFemaleButton = uibutton(app.UIFigure, 'push');
            app.MaleFemaleButton.ButtonPushedFcn = createCallbackFcn(app, @MaleFemaleButtonPushed, true);
            app.MaleFemaleButton.Text = 'MaleFemaleButton';
            app.MuffledButton = uibutton(app.UIFigure, 'push');
            app.MuffledButton.ButtonPushedFcn = createCallbackFcn(app, @MuffledButtonPushed, true);
            app.MuffledButton.Text = 'MuffledButton';
            app.PitchUpButton = uibutton(app.UIFigure, 'push');
            app.PitchUpButton.ButtonPushedFcn = createCallbackFcn(app, @PitchUpButtonPushed, true);
            app.PitchUpButton.Text = 'PitchUpButton';
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'PlayOriginalButton';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'SaveButton';
            app.AlienButton = uibutton(app.UIFigure, 'push');
            app.AlienButton.ButtonPushedFcn = createCallbackFcn(app, @AlienButtonPushed, true);
            app.AlienButton.Text = 'AlienButton';
            app.RobotButton = uibutton(app.UIFigure, 'push');
            app.RobotButton.ButtonPushedFcn = createCallbackFcn(app, @RobotButtonPushed, true);
            app.RobotButton.Text = 'RobotButton';
            app.EchoButton = uibutton(app.UIFigure, 'push');
            app.EchoButton.ButtonPushedFcn = createCallbackFcn(app, @EchoButtonPushed, true);
            app.EchoButton.Text = 'EchoButton';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'LoadButton';
            app.PitchDownButton = uibutton(app.UIFigure, 'push');
            app.PitchDownButton.ButtonPushedFcn = createCallbackFcn(app, @PitchDownButtonPushed, true);
            app.PitchDownButton.Text = 'PitchDownButton';
            app.OriginalVoiceLabel = uilabel(app.UIFigure);
            app.OriginalVoiceLabel.Text = 'Original Voice';
            app.ProcessedVoiceLabel = uilabel(app.UIFigure);
            app.ProcessedVoiceLabel.Text = 'Processed Voice';
            app.EffectsLabel = uilabel(app.UIFigure);
            app.EffectsLabel.Text = 'Effects';
            app.PlayProcessedButton = uibutton(app.UIFigure, 'push');
            app.PlayProcessedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayProcessedButtonPushed, true);
            app.PlayProcessedButton.Text = 'PlayProcessedButton';
            app.OrigionalSoundLabel = uilabel(app.UIFigure);
            app.OrigionalSoundLabel.Text = 'Origional Sound';
            app.ProcessedSoundLabel = uilabel(app.UIFigure);
            app.ProcessedSoundLabel.Text = 'Processed Sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VoiceChanger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end